classdef FMCW_GUIrev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PostProcessButton  matlab.ui.control.Button
        RunSlaveBtn        matlab.ui.control.Button
        RunMasterBtn       matlab.ui.control.Button
        GenerateBtn        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateBtn
        function GenerateBtnButtonPushed(app, event)
            transmitFile = 'R:\Temp\transmit.sc16q11';
            generateChirpWaveform(transmitFile);
            uialert(app.UIFigure, 'Waveform generated successfully.', 'Success');
        end

        % Button pushed function: RunMasterBtn
        function RunMasterBtnButtonPushed(app, event)
            try
                runMasterScript();
                uialert(app.UIFigure, 'MASTER script executed successfully.', 'Success');
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end
        end

        % Button pushed function: RunSlaveBtn
        function RunSlaveBtnButtonPushed(app, event)
            try
                runSlaveScript();
                uialert(app.UIFigure, 'SLAVE script executed successfully.', 'Success');
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end
        end

        % Button pushed function: PostProcessButton
        function PostProcessButtonPushed(app, event)
           %try
                % Run the standalone post-processing script
                run('Prototype4PostProcessing_FineDelayRev1.m');  % include full path if needed
           %     uialert(app.UIFigure, 'Post-processing completed successfully.', 'Success');
           %catch ME
           %     uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Post Processing Error');
           %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateBtn = uibutton(app.UIFigure, 'push');
            app.GenerateBtn.ButtonPushedFcn = createCallbackFcn(app, @GenerateBtnButtonPushed, true);
            app.GenerateBtn.Text = 'Generate Chirp';
            app.RunMasterBtn = uibutton(app.UIFigure, 'push');
            app.RunMasterBtn.ButtonPushedFcn = createCallbackFcn(app, @RunMasterBtnButtonPushed, true);
            app.RunMasterBtn.Text = 'Master';
            app.RunSlaveBtn = uibutton(app.UIFigure, 'push');
            app.RunSlaveBtn.ButtonPushedFcn = createCallbackFcn(app, @RunSlaveBtnButtonPushed, true);
            app.RunSlaveBtn.Text = 'Slave';
            app.PostProcessButton = uibutton(app.UIFigure, 'push');
            app.PostProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PostProcessButtonPushed, true);
            app.PostProcessButton.Text = 'Post Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMCW_GUIrev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end